classdef HORUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MainScreen                  matlab.ui.container.Panel
        MainGrid                    matlab.ui.container.GridLayout
        ConexinporcableCheckBox     matlab.ui.control.CheckBox
        PasswordField               matlab.ui.control.EditField
        UserNameField               matlab.ui.control.EditField
        AccederHTML                 matlab.ui.control.HTML
        Hyperlink                   matlab.ui.control.Hyperlink
        SignInStateLBL              matlab.ui.control.Label
        LOGOSEPA                    matlab.ui.control.Image
        SignInLBL                   matlab.ui.control.Label
        GraficasScreen              matlab.ui.container.Panel
        GraficasGrid                matlab.ui.container.GridLayout
        EYER                        matlab.ui.control.Image
        EYEL                        matlab.ui.control.Image
        AnalizarMedidasBT           matlab.ui.control.Button
        StartBT                     matlab.ui.control.Button
        GridLayout                  matlab.ui.container.GridLayout
        ConectedLamp                matlab.ui.control.Lamp
        ConectedLBL                 matlab.ui.control.Label
        VEOL                        matlab.ui.control.UIAxes
        HEOR                        matlab.ui.control.UIAxes
        HEOL                        matlab.ui.control.UIAxes
        VEOR                        matlab.ui.control.UIAxes
        ChooseActionScreen          matlab.ui.container.Panel
        ChooseActionGrid            matlab.ui.container.GridLayout
        BackChooseActionBT          matlab.ui.control.HTML
        ConectarGafasHTML           matlab.ui.control.HTML
        VerAnalisisPreviosHTML      matlab.ui.control.HTML
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        ConectScreen                matlab.ui.container.Panel
        ConectGrid                  matlab.ui.container.GridLayout
        BackConnectBT               matlab.ui.control.HTML
        TomarMedidasHTML            matlab.ui.control.HTML
        BuscarDevicesHTML           matlab.ui.control.HTML
        LogoHorusHTML               matlab.ui.control.HTML
        DevicesListHTML             matlab.ui.control.HTML
        WifiStatusHTML              matlab.ui.control.HTML
        ShowHiddenWifisCheckBox     matlab.ui.control.CheckBox
        AnalisisScreen              matlab.ui.container.Panel
        AnalisisGrid                matlab.ui.container.GridLayout
        ObservacionesLBL            matlab.ui.control.Label
        ObservacionesNamelbl        matlab.ui.control.Label
        EventsAnalisisButton        matlab.ui.control.Button
        TypeAnalisisLabel           matlab.ui.control.Label
        AnalisisTypeDropDown        matlab.ui.control.DropDown
        ExportButton                matlab.ui.control.Button
        miradasDerechaLbl           matlab.ui.control.Label
        miradasIzquierdaLbl         matlab.ui.control.Label
        UITableResultadosDerecha    matlab.ui.control.Table
        FatigaLabel                 matlab.ui.control.Label
        PatientInfoLabel            matlab.ui.control.Label
        FATIGAname                  matlab.ui.control.Label
        DATOSDELPACIENTELabel       matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        ANLISISPACIENTELabel        matlab.ui.control.Label
        UITableResultadosIzquierda  matlab.ui.control.Table
        GoBackButtonAnalisis        matlab.ui.control.Button
        EventsAnalisisScreen        matlab.ui.container.Panel
        EventsAnalisisGrid          matlab.ui.container.GridLayout
        EventsTable                 matlab.ui.control.Table
        TotalEventsLBL              matlab.ui.control.Label
        EventsAnalisisLBL           matlab.ui.control.Label
        GoBackButtonEvents          matlab.ui.control.Button
    end


    properties (Access = private)
        WifiOff
        AvailableWifis
        DevicesWifis
        WifiInterface
        WifiTemplate
        PasswordOptions = ["zeri3333","wifi.ROS1","00000000","12345678"];
        ConnectedDevice = false;
        Device
        SHEOL
        SVEOL
        SHEOR
        SVEOR
        LineSHEOL
        LineSVEOL
        LineSHEOR
        LineSVEOR
    end

    methods (Access = private)

        function ChangeWindowTo(app,screen)
            screens = [...
                app.MainScreen,...
                app.ChooseActionScreen,...
                app.ConectScreen,...
                app.GraficasScreen,...
                app.AnalisisScreen,...
                app.EventsAnalisisScreen
                ];
            for a = screens
                a.Visible = 'off';
            end
            screen.Visible = 'on';
        end
        function PrepareWifi(app)
            wifiName = "";
            if ispc

                % detectar adaptador wifi (incluso deshabilitado)
                cmd = ['powershell -Command "', ...
                    'Get-NetAdapter | Where-Object {$_.NdisPhysicalMedium -eq 9} | Select-Object -First 1 -ExpandProperty Name"'];

                [~,out] = system(cmd);
                wifiName = strtrim(out);

                if wifiName == ""
                    error("No se encontró adaptador WiFi");
                end
                % comprobar estado
                [~,interfaces] = system('netsh interface show interface');

                if ~contains(interfaces,"Enabled")

                    cmd = sprintf('netsh interface set interface "%s" admin=enabled',wifiName);
                    system(cmd);

                end
                app.WifiOff = 0;
                % desconectar wifi actual
                system('netsh wlan disconnect');
            else
                app.WifiInterface = wifiName;
                error("Sistema operativo no soportado")
            end
            app.WifiInterface = wifiName;
        end
        function FindAvailableWifis(app)
            app.AvailableWifis = strings(0);
            ssids = strings(0);

            if ispc
                % ----------------
                % Windows
                % ----------------;
                psCommand = sprintf([
                    '$wifi = Get-NetAdapter -Name "%s"; ' ...
                    'Disable-NetAdapter -Name $wifi.Name -Confirm:$false; ' ...
                    'Start-Sleep -Seconds 2; ' ...
                    'Enable-NetAdapter -Name $wifi.Name -Confirm:$false;'], app.WifiInterface);

                % Ejecutar desde MATLAB
                [status, out] = system(['powershell -Command "', psCommand, '"']);

                % Comprobar errores
                if status ~= 0
                    error("Error forzando reescaneo Wi-Fi:\n%s", out);
                end

                ready = false;

                for k = 1:10

                    pause(1)

                    [~,state] = system('netsh interface show interface');
                    disp(state)
                    disp(app.WifiInterface)
                    lines = splitlines(state);

                    % Buscar la línea de la interfaz WiFi
                    wifiLine = lines(contains(lines, app.WifiInterface));

                    if ~isempty(wifiLine) && contains(wifiLine,["Enabled","Habilitado"])
                        ready = true;
                        break
                    end

                end

                if ~ready
                    error("La interfaz WiFi no se activó correctamente")
                end

                timeout = 10;
                t0 = tic;

                while toc(t0) < timeout

                    [status,out] = system('netsh wlan show networks mode=bssid');

                    if status == 0
                        break
                    end

                    pause(0.5)

                end

                if status ~= 0
                    error("Error escaneando WiFi en Windows:\n%s", out)
                end
                % Cada red tiene línea "SSID 1 : NombreRed"
                lines = splitlines(out);
                ssidLines = lines(startsWith(strtrim(lines),'SSID '));
                for i = 1:length(ssidLines)
                    parts = split(ssidLines{i},':');
                    if numel(parts) >= 2
                        ssid = strtrim(parts{2});
                        if ~isempty(ssid)
                            ssids(end+1) = ssid;
                        end
                    end
                end
            else
                error("Sistema operativo no soportado")
            end

            % Limpiar duplicados y vacíos
            ssids = unique(ssids);
            app.AvailableWifis = flip(ssids(ssids ~= ""));
        end

        function ReloadListWifis(app,listWifis)
            app.DevicesListHTML.Data = {};
            app.DevicesListHTML.Data = listWifis;
        end
        function createWifiProfile(app,ssid,password)
            system("netsh wlan delete profile name=""" + ssid + """")
            app.WifiTemplate = fileread("wifi_template.xml");

            profile = strrep(app.WifiTemplate,"SSID_AQUI",ssid);
            profile = strrep(profile,"PASSWORD_AQUI",password);

            fid = fopen("wifi_temp.xml",'w');
            fprintf(fid,profile);
            fclose(fid);

            system('netsh wlan add profile filename="wifi_temp.xml" user=current');

        end

        function ChangeWifiStatus(app,state)
            app.WifiStatusHTML.Data = state;
        end

        function DataConnection(app)
            try
                app.Device = tcpclient("192.168.4.1",5000);
                disp('Conectado a ESP32');

            catch
                app.Device = [];  % devuelve vacío si falla
            end
        end

        function DataConnectionCable(app)
            try
                % https://es.mathworks.com/help/matlab/import_export/read-streaming-data-from-arduino.html
                % Cerramos las conexiones serialport existentes
                delete(serialportfind);
                % Guardamos los datos del serialport en "serialObj"
                app.Device = serialport("COM5",9600);
                % Definimos la propiedad Terminator para que coincida con el terminador
                % especificado en el codigo de arduino
                configureTerminator(app.Device,"CR/LF");
                % Vaciamos serialObj para limpiar datos antiguos
                flush(app.Device);

            catch
                app.Device = [];  % devuelve vacío si falla
            end

        end

        function RefreshWifis(app)
            ChangeWifiStatus(app,'desconectado');
            app.TomarMedidasHTML.Visible = 'off';
            ReloadListWifis(app,{});
            LoadingWifis = uiprogressdlg(app.UIFigure, ...
                'Title', 'Buscando Dispositivos...', 'Message', 'Porfavor espere mientras se cargan los dispositivos...', 'Indeterminate', 'on');
            drawnow
            FindAvailableWifis(app);
            if isempty(app.AvailableWifis)
                close(LoadingWifis);
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ninguna Wifi Encontrada');
            else
                %const char* ssid = "ESP32SEPA"; // NOMBRE DE LA RED A CREAR
                %const char* password = "zeri3333";   // PASSWORD DE LA RED (MINIMO 8CHAR)
                comparador = contains(app.AvailableWifis, "ROS", "IgnoreCase", true);
                app.DevicesWifis = app.AvailableWifis(comparador);
                if isempty(app.DevicesWifis)
                    uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido o busque en Redes Escondidas','Ningun Dispositivo Encontrado','Icon','warning');
                end
                ReloadListWifis(app, app.DevicesWifis);
                close(LoadingWifis);
            end
        end

        function TomarMedidasClicked(app)
            ChangeWindowTo(app,app.GraficasScreen);
        end

        function AccederClicked(app)
            username = app.UserNameField.Value;
            password = app.PasswordField.Value;
            if strcmp(username, 'CABLE') && strcmp(password, 'CABLE')
                ChangeWindowTo(app,app.GraficasScreen);
            else
                ChangeWindowTo(app,app.ChooseActionScreen);
            end
        end

        function ConectarGafasClicked(app)
            try
                PrepareWifi(app);
            catch ME
                uialert(app.UIFigure, ME.message, "Error WiFi");
            end
            ChangeWindowTo(app,app.ConectScreen);
            RefreshWifis(app);
        end

        function VerAnalisisPreviosClicked(app)
            app.VerAnalisisPreviosHTML;
        end

        function DataAcquisition(app)
            app.StartBT.Text = 'STOP';
            app.StartBT.BackgroundColor = 'RED';
            app.SHEOL = [];
            app.SVEOL = [];
            app.SHEOR = [];
            app.SVEOR = [];

            % Create figure and animated lines
            app.LineSHEOL = animatedline(app.HEOL, 'Color','r');
            app.LineSVEOL = animatedline(app.VEOL, 'Color','g');
            app.LineSHEOR = animatedline(app.HEOR, 'Color','b');
            app.LineSVEOR = animatedline(app.VEOR, 'Color','BLACK');

            windowSize = 50;
            k = 0; % variable para utilizar despues
            % Creamos un loop para recoger los datos que guardamos en t
            while true
                if app.Device.NumBytesAvailable > 0
                    % Guardamos los datos en "data"
                    data = readline(app.Device);
                    % Separamos lo que recogemos en data en un array "valores" que separa los datos recogidos por una ","
                    valores = str2double(split(strtrim(data),","));
                    % Separamos los valores y los guardamos en variables por cada señal que extraemos
                    if length(valores) == 4
                        s1 = valores(1);
                        s2 = valores(2);
                        s3 = valores(3);
                        s4 = valores(4);
                        disp(['RAW: ', num2str([s1 s2 s3 s4])]);

                        S1mV = s1 * 0.80586080586;
                        S2mV = s2 * 0.80586080586;
                        S3mV = s3 * 0.80586080586;
                        S4mV = s4 * 0.80586080586;
                        % Guardamos los datos de cada señal en el final de su array correspondiente
                        app.SHEOL(end+1) = S1mV;
                        app.SVEOL(end+1) = S2mV;
                        app.SHEOR(end+1) = S3mV;
                        app.SVEOR(end+1) = S4mV;
                        % Increase sample counter
                        k = k + 1;

                        % ADD DATA TO THE PLOT
                        addpoints(app.LineSHEOL,k,S1mV)
                        addpoints(app.LineSVEOL,k,S2mV)
                        addpoints(app.LineSHEOR,k,S3mV)
                        addpoints(app.LineSVEOR,k,S4mV)
                        if k > windowSize
                            app.HEOL.XLim = [k-windowSize, k];
                            app.VEOL.XLim = [k-windowSize, k];
                            app.HEOR.XLim = [k-windowSize, k];
                            app.VEOR.XLim = [k-windowSize, k];
                        end
                        drawnow limitrate
                        % Hacesmos display de los datos en consola
                        %disp([S1mV,S2mV,S3mV,S4mV]);
                        % Pausa de la funcion de 50Hz (0.02 segundos) para graficar
                        pause(0.02)

                    end
                end


            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AccederHTML.Visible = 'off';
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;
            app.BackChooseActionBT.HTMLSource = "GoBack.html";
            app.BackChooseActionBT.Data = struct('id', 'ChooseActionScreen');
            app.BackConnectBT.HTMLSource = "GoBack.html";
            app.BackConnectBT.Data = struct('id', 'ConectScreen');
            app.WifiStatusHTML.HTMLSource= 'wifi_status.html';
            app.DevicesListHTML.HTMLSource = 'device_list.html';
            app.LogoHorusHTML.HTMLSource = 'logo_horus.html';
            app.LogoHorusHTML.Data = struct('color', '#808080');
            app.BuscarDevicesHTML.HTMLSource = 'buscar_dispositivo_button.html';

            buttons = [...
                app.TomarMedidasHTML,...
                app.AccederHTML,...
                app.ConectarGafasHTML,...
                app.VerAnalisisPreviosHTML
                ];
            for b = buttons
                b.HTMLSource = 'Button.html';
            end
            app.AccederHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ecffff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '26px',    ...
                'label',        'Acceder' ...
                );
            app.TomarMedidasHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#6a06b3', ...
                'borderColor',  '#9370db', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '18px',    ...
                'label',        'Tomar Medidas' ...
                );
            app.ConectarGafasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '16px',    ...
                'label',        'Conectar Gafas Horus' ...
                );
            app.VerAnalisisPreviosHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '16px',    ...
                'label',        'Ver Analisis Previos' ...
                );
            ChangeWindowTo(app,app.MainScreen);
            app.AccederHTML.Visible = 'on';
        end

        % Value changed function: ShowHiddenWifisCheckBox
        function ShowHiddenWifisClicked(app, event)
            value = app.ShowHiddenWifisCheckBox.Value;
            if value == 1
                ReloadListWifis(app,app.AvailableWifis);
            else
                ReloadListWifis(app,app.DevicesWifis);
            end
        end

        % Button pushed function: StartBT
        function StartClicked(app, event)
            username = app.UserNameField.Value;
            password = app.PasswordField.Value;
            if strcmp(username, 'CABLE') && strcmp(password, 'CABLE')
                DataConnectionCable(app);
                disp(app.Device);
            else
                DataConnection(app);
                disp(app.Device);
            end
            DataAcquisition(app);
        end

        % Event received function: DevicesListHTML
        function DevicesListClicked(app, event)
            eventData = event.HTMLEventData;
            value = eventData.name;
            ChangeWifiStatus(app,'conectando');
            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connecting');
            ConectingWifi = uiprogressdlg(app.UIFigure, ...
                'Title', 'Conectando...', 'Message', 'Conectandose al Dispositivo', 'Indeterminate', 'on');
            drawnow
            for p = app.PasswordOptions
                ConectingWifi.Message = 'Probando Contraseña: ' + p;
                createWifiProfile(app,value,p);

                system("netsh wlan connect name=""" + value + """");

                timeout = 8;
                t0 = tic;

                while toc(t0) < timeout

                    [~,out] = system('netsh wlan show interfaces');

                    % comprobar estado
                    stateLine = regexp(out,'Estado\s*:\s*([^\r\n]*)','tokens','once');

                    % comprobar SSID
                    ssidLine = regexp(out,'SSID\s*:\s*([^\r\n]*)','tokens','once');

                    if ~isempty(stateLine) && ~isempty(ssidLine)

                        state = strtrim(lower(stateLine{1}));
                        currentSSID = strtrim(ssidLine{1});
                        disp("Estado: " + state)
                        disp("SSID: " + currentSSID)

                        if (strcmp(state,"conectado") || strcmp(state,"connected")) && strcmp(currentSSID,value)

                            disp("Conectado con: " + p);
                            app.ConnectedDevice = true;
                            close(ConectingWifi);
                            uialert(app.UIFigure,'Dispositivo Conectado','Conexion Hecha','Icon','success');
                            app.TomarMedidasHTML.Visible = 'on';
                            ChangeWifiStatus(app,'conectado');
                            app.LogoHorusHTML.Data = struct('color', '#95F18B');
                            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connected');
                            return

                        end

                    end

                    pause(0.3)

                end
                ChangeWifiStatus(app,'error');
                app.LogoHorusHTML.Data = struct('color', '#FF3333');
            end

        end

        % Event received function: BuscarDevicesHTML
        function BuscarDevicesClicked(app, event)
            if strcmp(event.HTMLEventName, "ButtonClicked")
                RefreshWifis(app);
            end
        end

        % Event received function: AccederHTML, ConectarGafasHTML, 
        % ...and 2 other components
        function ButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'ButtonClicked')
                if strcmp(eventData, 'Tomar Medidas')
                    TomarMedidasClicked(app);
                elseif strcmp(eventData, 'Acceder')
                    AccederClicked(app);
                elseif strcmp(eventData, 'Conectar Gafas Horus')
                    ConectarGafasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                end
            end
        end

        % Event received function: BackChooseActionBT, BackConnectBT
        function BackButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData.id;
            disp(eventData);
            if strcmp(eventName, 'buttonClicked')
                if strcmp(eventData, 'ChooseActionScreen')
                    ChangeWindowTo(app,app.MainScreen);
                elseif strcmp(eventData, 'ConectScreen')
                    ChangeWindowTo(app,app.ChooseActionScreen);
                elseif strcmp(eventData, 'Conectar Gafas Horus')
                    ConectarGafasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EventsAnalisisScreen = uipanel(app.UIFigure);
            app.EventsAnalisisGrid = uigridlayout(app.EventsAnalisisScreen);
            app.GoBackButtonEvents = uibutton(app.EventsAnalisisGrid, 'push');
            app.GoBackButtonEvents.Text = 'Go Back';
            app.EventsAnalisisLBL = uilabel(app.EventsAnalisisGrid);
            app.EventsAnalisisLBL.Text = 'Analisis de Eventos Oculares';
            app.TotalEventsLBL = uilabel(app.EventsAnalisisGrid);
            app.TotalEventsLBL.Text = 'Todos los Eventos';
            app.EventsTable = uitable(app.EventsAnalisisGrid);
            app.EventsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EventsTable.RowName = {};
            app.AnalisisScreen = uipanel(app.UIFigure);
            app.AnalisisGrid = uigridlayout(app.AnalisisScreen);
            app.GoBackButtonAnalisis = uibutton(app.AnalisisGrid, 'push');
            app.GoBackButtonAnalisis.Text = 'Go Back';
            app.UITableResultadosIzquierda = uitable(app.AnalisisGrid);
            app.UITableResultadosIzquierda.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosIzquierda.RowName = {};
            app.ANLISISPACIENTELabel = uilabel(app.AnalisisGrid);
            app.ANLISISPACIENTELabel.Text = 'ANÁLISIS PACIENTE';
            app.Image_2 = uiimage(app.AnalisisGrid);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PERSON-ICON.png');
            app.DATOSDELPACIENTELabel = uilabel(app.AnalisisGrid);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.FATIGAname = uilabel(app.AnalisisGrid);
            app.FATIGAname.Text = 'FATIGA:';
            app.PatientInfoLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel.Text = 'Label2';
            app.UITableResultadosDerecha = uitable(app.AnalisisGrid);
            app.UITableResultadosDerecha.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosDerecha.RowName = {};
            app.miradasIzquierdaLbl = uilabel(app.AnalisisGrid);
            app.miradasIzquierdaLbl.Text = 'Izq';
            app.miradasDerechaLbl = uilabel(app.AnalisisGrid);
            app.miradasDerechaLbl.Text = 'Der';
            app.ExportButton = uibutton(app.AnalisisGrid, 'push');
            app.ExportButton.Text = 'Export to .tsv';
            app.AnalisisTypeDropDown = uidropdown(app.AnalisisGrid);
            app.AnalisisTypeDropDown.Items = {'All', 'Tipo 1', 'Tipo 2'};
            app.AnalisisTypeDropDown.Value = 'All';
            app.TypeAnalisisLabel = uilabel(app.AnalisisGrid);
            app.TypeAnalisisLabel.Text = 'Total Analisis';
            app.EventsAnalisisButton = uibutton(app.AnalisisGrid, 'push');
            app.EventsAnalisisButton.Text = 'Events Analisis';
            app.ObservacionesNamelbl = uilabel(app.AnalisisGrid);
            app.ObservacionesNamelbl.Text = 'OBSERVACIONES:';
            app.ObservacionesLBL = uilabel(app.AnalisisGrid);
            app.ObservacionesLBL.Text = 'Label2';
            app.ConectScreen = uipanel(app.UIFigure);
            app.ConectGrid = uigridlayout(app.ConectScreen);
            app.ShowHiddenWifisCheckBox = uicheckbox(app.ConectGrid);
            app.ShowHiddenWifisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHiddenWifisClicked, true);
            app.ShowHiddenWifisCheckBox.Text = 'Enseñar Redes Escondidas';
            app.WifiStatusHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @DevicesListClicked, true);
            app.LogoHorusHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BuscarDevicesClicked, true);
            app.TomarMedidasHTML = uihtml(app.ConectGrid);
            app.TomarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackConnectBT = uihtml(app.ConectGrid);
            app.BackConnectBT.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.ChooseActionScreen = uipanel(app.UIFigure);
            app.ChooseActionGrid = uigridlayout(app.ChooseActionScreen);
            app.Image = uiimage(app.ChooseActionGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GAFAS-HORUS-LOGO.svg');
            app.Image2 = uiimage(app.ChooseActionGrid);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'patient.svg');
            app.VerAnalisisPreviosHTML = uihtml(app.ChooseActionGrid);
            app.VerAnalisisPreviosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectarGafasHTML = uihtml(app.ChooseActionGrid);
            app.ConectarGafasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackChooseActionBT = uihtml(app.ChooseActionGrid);
            app.BackChooseActionBT.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.GraficasScreen = uipanel(app.UIFigure);
            app.GraficasGrid = uigridlayout(app.GraficasScreen);
            app.VEOR = uiaxes(app.GraficasGrid);
            title(app.VEOR, 'VEOR')
            zlabel(app.VEOR, 'Z')
            app.HEOL = uiaxes(app.GraficasGrid);
            title(app.HEOL, 'HEOL')
            zlabel(app.HEOL, 'Z')
            app.HEOR = uiaxes(app.GraficasGrid);
            title(app.HEOR, 'HEOR')
            zlabel(app.HEOR, 'Z')
            app.VEOL = uiaxes(app.GraficasGrid);
            title(app.VEOL, 'VEOL')
            zlabel(app.VEOL, 'Z')
            app.GridLayout = uigridlayout(app.GraficasGrid);
            app.ConectedLBL = uilabel(app.GridLayout);
            app.ConectedLBL.Text = 'Conected';
            app.ConectedLamp = uilamp(app.GridLayout);
            app.StartBT = uibutton(app.GraficasGrid, 'push');
            app.StartBT.ButtonPushedFcn = createCallbackFcn(app, @StartClicked, true);
            app.StartBT.Icon = 'play.svg';
            app.StartBT.Text = 'START';
            app.AnalizarMedidasBT = uibutton(app.GraficasGrid, 'push');
            app.AnalizarMedidasBT.Text = 'Analizar Medidas';
            app.EYEL = uiimage(app.GraficasGrid);
            app.EYEL.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYER = uiimage(app.GraficasGrid);
            app.EYER.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.MainScreen = uipanel(app.UIFigure);
            app.MainGrid = uigridlayout(app.MainScreen);
            app.SignInLBL = uilabel(app.MainGrid);
            app.SignInLBL.Text = 'Inicia Sesión';
            app.LOGOSEPA = uiimage(app.MainGrid);
            app.LOGOSEPA.ImageSource = fullfile(pathToMLAPP, 'LOGO-SEPA-EXPANDED.png');
            app.SignInStateLBL = uilabel(app.MainGrid);
            app.SignInStateLBL.Text = 'SignInStateLBL';
            app.Hyperlink = uihyperlink(app.MainGrid);
            app.Hyperlink.Text = 'Crear Cuenta';
            app.AccederHTML = uihtml(app.MainGrid);
            app.AccederHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.UserNameField = uieditfield(app.MainGrid, 'text');
            app.PasswordField = uieditfield(app.MainGrid, 'text');
            app.ConexinporcableCheckBox = uicheckbox(app.MainGrid);
            app.ConexinporcableCheckBox.Text = 'Conexión por cable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HORUS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end